classdef gas_outflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GasoverflowUIFigure          matlab.ui.Figure
        Label                        matlab.ui.control.Label
        VesselPanel                  matlab.ui.container.Panel
        InitialPressureEditField     matlab.ui.control.NumericEditField
        VesselsVolumeEditField       matlab.ui.control.NumericEditField
        InitialPressureLabel         matlab.ui.control.Label
        BarsLabel                    matlab.ui.control.Label
        VesselsvolumeLabel           matlab.ui.control.Label
        m3Label                      matlab.ui.control.Label
        InitialtemperatureLabel      matlab.ui.control.Label
        InitialtemperatureEditField  matlab.ui.control.NumericEditField
        KelvinsLabel                 matlab.ui.control.Label
        GasPanel                     matlab.ui.container.Panel
        MolecularweightLabel         matlab.ui.control.Label
        gmolLabel                    matlab.ui.control.Label
        MolecularweightEditField_2   matlab.ui.control.NumericEditField
        SpecificheatatconstantvolumeLabel  matlab.ui.control.Label
        kjkkgLabel                   matlab.ui.control.Label
        specheatEditField            matlab.ui.control.NumericEditField
        PoissonscoeffLabel           matlab.ui.control.Label
        poissonEditField             matlab.ui.control.NumericEditField
        OutflowPanel                 matlab.ui.container.Panel
        DischargecoefficientLabel    matlab.ui.control.Label
        DischargeEditField_3         matlab.ui.control.NumericEditField
        HolesdiameterLabel           matlab.ui.control.Label
        cmLabel                      matlab.ui.control.Label
        diameterEditField_3          matlab.ui.control.NumericEditField
        TimestepLabel                matlab.ui.control.Label
        timeStepEditField_4          matlab.ui.control.NumericEditField
        sLabel                       matlab.ui.control.Label
        AmbiantPressureEditField_2   matlab.ui.control.NumericEditField
        AmbiantpressureLabel         matlab.ui.control.Label
        BarsLabel_2                  matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        GasoverflowLabel             matlab.ui.control.Label
        AboutButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        V = 0;  % Description
        P0 = 0;
        Pa = 0; 
        T0=0; 
        D0 = 0 ;
        Cd = 0 ; 
        Gamma = 0 ;
        Wg = 0 ; 
        Cv = 0 ; 
        tS = 0 ; 
    end
    
    methods (Access = private)
        
        function status = readFields(app)
            
            status = 0;
            
          app.Pa =   app.AmbiantPressureEditField_2.Value;
          app.P0 =  app.InitialPressureEditField.Value;
          app.V =  app.VesselsVolumeEditField.Value;
          app.D0 =   app.diameterEditField_3.Value;
          app.Cd =  app.DischargeEditField_3.Value;
          app.T0 =   app.InitialtemperatureEditField.Value;
          app.Wg =  app.MolecularweightEditField_2.Value;
          app.Gamma =  app.poissonEditField.Value;
          app.Cv =  app.specheatEditField.Value;
          app.tS =  app.timeStepEditField_4.Value;
          
          if (app.P0 <= app.Pa)
              opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
              f = errordlg('The initial pressure of the vessel should not be lower than ambiant pressure ','Error',opts);

              status = -1;
          end


        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            
         status = app.readFields();
         
         if (status ~= -1)
             
            gas_outflow_function(app.V, app.P0, app.T0, app.Cd , app.Wg, app.Cv , app.Gamma, app.Pa, app.D0, app.tS);

         end


        end

        % Close request function: GasoverflowUIFigure
        function GasoverflowUIFigureCloseRequest(app, event)
            
            close all ; 
            clc ; 
            delete(app);
            
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)


            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            h=msgbox('This application was developed by YAKHOU Nazim for educational purposes only.\newline\bfReference:\rm\newlineThis application''s algorithm is based on the models presented in the book\bf"Fires, Explosions, and Toxic Gas Dispersions: Effects Calculation and Risk Analysis"\rm(Assael & Kakosimos)\newlineISBN : 9781439826751','About','help',CreateStruct);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GasoverflowUIFigure = uifigure;
            app.GasoverflowUIFigure.Name = 'Gas overflow';
            app.GasoverflowUIFigure.CloseRequestFcn = createCallbackFcn(app, @GasoverflowUIFigureCloseRequest, true);
            app.Label = uilabel(app.GasoverflowUIFigure);
            app.Label.Text = '';
            app.VesselPanel = uipanel(app.GasoverflowUIFigure);
            app.VesselPanel.Title = 'Vessel';
            app.InitialPressureEditField = uieditfield(app.VesselPanel, 'numeric');
            app.InitialPressureEditField.Limits = [1 500];
            app.InitialPressureEditField.Value = 2;
            app.VesselsVolumeEditField = uieditfield(app.VesselPanel, 'numeric');
            app.VesselsVolumeEditField.Limits = [1e-05 Inf];
            app.VesselsVolumeEditField.Value = 1;
            app.InitialPressureLabel = uilabel(app.VesselPanel);
            app.InitialPressureLabel.Text = 'Initial Pressure';
            app.BarsLabel = uilabel(app.VesselPanel);
            app.BarsLabel.Text = 'Bars';
            app.VesselsvolumeLabel = uilabel(app.VesselPanel);
            app.VesselsvolumeLabel.Text = 'Vessel''s volume';
            app.m3Label = uilabel(app.VesselPanel);
            app.m3Label.Text = 'm3';
            app.InitialtemperatureLabel = uilabel(app.VesselPanel);
            app.InitialtemperatureLabel.Text = {'Initial '; 'temperature'};
            app.InitialtemperatureEditField = uieditfield(app.VesselPanel, 'numeric');
            app.InitialtemperatureEditField.Limits = [0 Inf];
            app.InitialtemperatureEditField.Value = 1;
            app.KelvinsLabel = uilabel(app.VesselPanel);
            app.KelvinsLabel.Text = 'Kelvins';
            app.GasPanel = uipanel(app.GasoverflowUIFigure);
            app.GasPanel.Title = 'Gas';
            app.MolecularweightLabel = uilabel(app.GasPanel);
            app.MolecularweightLabel.Text = 'Molecular weight';
            app.gmolLabel = uilabel(app.GasPanel);
            app.gmolLabel.Text = 'g/mol';
            app.MolecularweightEditField_2 = uieditfield(app.GasPanel, 'numeric');
            app.MolecularweightEditField_2.Limits = [1 Inf];
            app.MolecularweightEditField_2.Value = 1;
            app.SpecificheatatconstantvolumeLabel = uilabel(app.GasPanel);
            app.SpecificheatatconstantvolumeLabel.Text = {'Specific heat at '; 'constant volume'};
            app.kjkkgLabel = uilabel(app.GasPanel);
            app.kjkkgLabel.Text = 'kj/k/kg';
            app.specheatEditField = uieditfield(app.GasPanel, 'numeric');
            app.specheatEditField.Limits = [0 Inf];
            app.specheatEditField.Value = 1;
            app.PoissonscoeffLabel = uilabel(app.GasPanel);
            app.PoissonscoeffLabel.Text = 'Poissons coeff';
            app.poissonEditField = uieditfield(app.GasPanel, 'numeric');
            app.poissonEditField.Limits = [1 Inf];
            app.poissonEditField.Value = 1.4;
            app.OutflowPanel = uipanel(app.GasoverflowUIFigure);
            app.OutflowPanel.Title = 'Outflow';
            app.DischargecoefficientLabel = uilabel(app.OutflowPanel);
            app.DischargecoefficientLabel.Tooltip = {'Typically : 0.62 or 0.98'};
            app.DischargecoefficientLabel.Text = {'Discharge '; 'coefficient'};
            app.DischargeEditField_3 = uieditfield(app.OutflowPanel, 'numeric');
            app.DischargeEditField_3.Limits = [0.1 1];
            app.DischargeEditField_3.Tooltip = {'Typically : 0.62 or 0.98'};
            app.DischargeEditField_3.Value = 0.62;
            app.HolesdiameterLabel = uilabel(app.OutflowPanel);
            app.HolesdiameterLabel.Text = 'Hole''s diameter';
            app.cmLabel = uilabel(app.OutflowPanel);
            app.cmLabel.Text = 'cm';
            app.diameterEditField_3 = uieditfield(app.OutflowPanel, 'numeric');
            app.diameterEditField_3.Limits = [1 Inf];
            app.diameterEditField_3.Value = 1;
            app.TimestepLabel = uilabel(app.OutflowPanel);
            app.TimestepLabel.Text = 'Time step';
            app.timeStepEditField_4 = uieditfield(app.OutflowPanel, 'numeric');
            app.timeStepEditField_4.Limits = [0.01 Inf];
            app.timeStepEditField_4.Value = 1;
            app.sLabel = uilabel(app.OutflowPanel);
            app.sLabel.Text = 's';
            app.AmbiantPressureEditField_2 = uieditfield(app.OutflowPanel, 'numeric');
            app.AmbiantPressureEditField_2.Limits = [0.7 1.3];
            app.AmbiantPressureEditField_2.Value = 1.013;
            app.AmbiantpressureLabel = uilabel(app.OutflowPanel);
            app.AmbiantpressureLabel.Text = 'Ambiant pressure';
            app.BarsLabel_2 = uilabel(app.OutflowPanel);
            app.BarsLabel_2.Text = 'Bars';
            app.StartButton = uibutton(app.GasoverflowUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start  >';
            app.GasoverflowLabel = uilabel(app.GasoverflowUIFigure);
            app.GasoverflowLabel.Text = 'Gas overflow';
            app.AboutButton = uibutton(app.GasoverflowUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gas_outflow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GasoverflowUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GasoverflowUIFigure)
        end
    end
end