classdef fireball < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FireballUIFigure                matlab.ui.Figure
        StartButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ScenarioTab                     matlab.ui.container.Tab
        VesselPanel                     matlab.ui.container.Panel
        Vesselsvolumem3EditFieldLabel   matlab.ui.control.Label
        Vesselsvolumem3EditField        matlab.ui.control.NumericEditField
        GasfractionEditFieldLabel       matlab.ui.control.Label
        GasfractionEditField            matlab.ui.control.NumericEditField
        GaspressureMPaEditFieldLabel    matlab.ui.control.Label
        GaspressureMPaEditField         matlab.ui.control.NumericEditField
        FlamablesubstancePanel          matlab.ui.container.Panel
        CombustionheatkjkgEditFieldLabel  matlab.ui.control.Label
        CombustionheatkjkgEditField     matlab.ui.control.NumericEditField
        DensityKgm3EditFieldLabel       matlab.ui.control.Label
        DensityKgm3EditField            matlab.ui.control.NumericEditField
        AmbiantconditionsPanel          matlab.ui.container.Panel
        Humidityratio01EditFieldLabel   matlab.ui.control.Label
        Humidityratio01EditField        matlab.ui.control.NumericEditField
        SaturationvapourpressurePaEditFieldLabel  matlab.ui.control.Label
        SaturationvapourpressurePaEditField  matlab.ui.control.NumericEditField
        ThermaleffectsPanel             matlab.ui.container.Panel
        PopulationdensitypersonmEditFieldLabel  matlab.ui.control.Label
        PopulationdensitypersonmEditField  matlab.ui.control.NumericEditField
        BodycoverageimpactofclothesDropDownLabel  matlab.ui.control.Label
        BodycoverageimpactofclothesDropDown  matlab.ui.control.DropDown
        ModelTab                        matlab.ui.container.Tab
        Applyasafetyfactorofx2CheckBox  matlab.ui.control.CheckBox
        ComputationsresolutionDropDownLabel  matlab.ui.control.Label
        ComputationsresolutionDropDown  matlab.ui.control.DropDown
        TargetsTab                      matlab.ui.container.Tab
        AddtargetPanel                  matlab.ui.container.Panel
        TargetdistancemEditFieldLabel   matlab.ui.control.Label
        TargetdistancemEditField        matlab.ui.control.NumericEditField
        AddentreeButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RemoveentreeButton              matlab.ui.control.Button
        DefinecustomtargetsLabel        matlab.ui.control.Label
        FireballLabel_2                 matlab.ui.control.Label
    end

    
    properties (Access = public)
       
        %% User input 
            
        gas_frac =  0 ; %fraction of gas inside the vessel [0-1]
        combustion_heat = 0 ; %heat of combustion of the flamable substance (Kj/Kg)
        density = 0 ; % of flamable substance - kg/m3
        Psv= 0; % vapour pressure inside the vessel in MPa
        vessel_volume =  0 ;  % initial TOTAL volume of the vessel - m3
        
        Pow  = 0 ;% saturation vapour pressure in the air in Pa
        RH = 0 ;% humidity ratio in [0-1]
        
        Resolution  = 125 ; % 125 250 500 
        safety_factor = 1 ; % 1 or 2 
        Fk = 1 ; % % body coverage summer 0.94 winter 0.14 no effect 1
        N0 = 0 ; % human density ppl/m²
        
        
        %% Computed values 

        initial_mass= 0;   % total mass of flamable cloud (kg)
        D_max = 0;  % max diameter of the fireball after 1/3 of its duration (in Kg)
        t_max = 0;  % duration of the fireball (in s)
        D_t = 0 ; % array of diameter over time 
        t = 0 ; %  array of time 
        fireball_height = 0 ; 
        burning_rate = 0  ; %of flamable substance - kg/s
        SEP = 0 ; % surface emitting power in Kw/m² 
        
        
        heat_flux_batch1D = 0 ; R_1D = 0 ; 
        X = 0 ;  Y = 0 ; heat_flux_batch2D = 0 ; 
        
        heatflux_from_distance_fit = 0 ; gof = 0 ; str = " " ; 
        distance_from_heatflux_fit = 0 ;
        
        P_level1_1d_batch = 0 ;P_level2_1d_batch = 0; P_level3_1d_batch = 0 ;
        ovr_injuries_lv1 = 0 ; ovr_injuries_lv2 = 0; ovr_injuries_lv3 = 0 ;
        P_level1_2d_batch = 0;  P_level2_2d_batch =0;  P_level3_2d_batch =0;
        
        
        % Custom Targets array 
        max_targets = 25 ; 
        custom_targets_array = 0 ; 
        custom_targets_results = 0 ;
        custom_targets_counter = 0 ; 
        current_row = 0 ;
        idx = 0 ;
        
         %% Waitbar 
        f = 0 ;
        %% table 
        fx = 0 ;
        %% table 2 
        fx2 = 0 ;
        
        
    end
    
    methods (Access = private)
        
        function read_input_fields(app)
        app.vessel_volume =    app.Vesselsvolumem3EditField.Value ; 
        app.gas_frac =  app.GasfractionEditField.Value ; 
        app.Psv  =  app.GaspressureMPaEditField.Value ; 
        app.combustion_heat  =  app.CombustionheatkjkgEditField.Value ; 
        app.density  =  app.DensityKgm3EditField.Value ; 
        app.RH  =  app.Humidityratio01EditField.Value ; 
        app.Pow  =  app.SaturationvapourpressurePaEditField.Value ; 
        app.N0  =  app.PopulationdensitypersonmEditField.Value ; 
        app.Fk =  str2double(app.BodycoverageimpactofclothesDropDown.Value) ; %dropdown
        if (app.Applyasafetyfactorofx2CheckBox.Value)
            app.safety_factor = 2 ;
         else
            app.safety_factor = 1 ; 
         end  
        app.Resolution  =  str2num(app.ComputationsresolutionDropDown.Value) ; % dropdown
        
         app.custom_targets_array = app.UITable.Data ; 
        end
        
        function run_computations(app)
        %% Computations
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.12,app.f,'#Step 2/7 #Running initial computations');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        app.initial_mass = initial_mass_computation(app.gas_frac,app.vessel_volume,app.density);
        [app.D_max , app.t_max] = max_parameters_computation_TNO(app.initial_mass) ;
        app.fireball_height = app.D_max ;
        app.burning_rate = burning_rate_computation(app.D_max,app.t_max,app.initial_mass);
        app.SEP = SEP_computation(app.Psv,app.burning_rate,app.combustion_heat);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.24,app.f,'#Step 3/7 #Computing heatflux');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        [app.R_1D,app.heat_flux_batch1D] = batch_heat_flux_computation_1D(app.D_max,app.Resolution,app.fireball_height,app.Pow,app.RH,app.SEP );
        if(app.safety_factor > 1)
            app.heat_flux_batch1D = app.safety_factor.*app.heat_flux_batch1D ;
        end
        %%
        waitbar(.36,app.f,'#Step 4/7 #Generating 2D heatmap');
        %%
        [app.X,app.Y,app.heat_flux_batch2D] = batch_heat_flux_computation_2D(app.D_max,app.Resolution,app.fireball_height,app.Pow,app.RH,app.SEP );
        if(app.safety_factor > 1)
            app.heat_flux_batch2D = app.heat_flux_batch2D.*app.safety_factor; 
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.48,app.f,'#Step 5/7 #Fitting model');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % curve fitting 
        [app.heatflux_from_distance_fit, app.gof] = fit_get_heatflux(app.R_1D, app.heat_flux_batch1D);
         app.str ={formula(app.heatflux_from_distance_fit),' parameters resp : ',num2str(coeffvalues(app.heatflux_from_distance_fit)),'R^2 = ',app.gof.rsquare };
        [app.distance_from_heatflux_fit] = fit_get_distance(app.heat_flux_batch1D, app.R_1D);
        % Thermal effects 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Effetcs
        waitbar(.60,app.f,'#Step 6/7 #Computing thermal effects');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        [app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch ] = batch_ppl_effect_computation_1D(app.t_max,app.Fk,app.R_1D,  app.D_max , app.distance_from_heatflux_fit  ,app.heatflux_from_distance_fit);
        [app.ovr_injuries_lv2,app.ovr_injuries_lv2,app.ovr_injuries_lv3] = overall_effects_computation(app.N0,app.D_max,app.R_1D,app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch,app.distance_from_heatflux_fit);
        %%
        waitbar(.72,app.f,'#Step 7/7 #Generating 2D thermal effects map');
        %%          
        [app.P_level1_2d_batch , app.P_level2_2d_batch ,  app.P_level3_2d_batch] = batch_ppl_effect_computation_2D(app.t_max,app.Fk, app.X,app.Y,app.D_max , app.distance_from_heatflux_fit  ,app.heatflux_from_distance_fit);
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        if (app.custom_targets_counter>0 )
            waitbar(0.9,app.f,'Processing custom targets');
            
            w = size(app.custom_targets_array,1); 
            
            app.custom_targets_results = zeros(w ,6);
             
            
                for i=1:w
                    F_12_trgt = view_factor_computation(app.D_max,app.fireball_height,app.custom_targets_array(i,2) ) ;
                    trs_trgt = transmissivity(app.Pow,app.RH,app.custom_targets_array(i,2),app.D_max);
                    hf = single_heatflux_computation(app.SEP,F_12_trgt,trs_trgt);
                    [a1,a2, a3] = ppl_effect_computation(app.t_max,app.Fk , app.custom_targets_array(i,2), app.D_max , app.distance_from_heatflux_fit  ,app.heatflux_from_distance_fit,hf);
                    app.custom_targets_results(i,:) = [floor(app.custom_targets_array(i,1)) app.custom_targets_array(i,2) app.safety_factor*hf 100*a1 100*a2 100*a3] ;
                end


        end   
        
        
        waitbar(1,app.f,'Finishing');
        pause(1)
        close(app.f)     
            
            
        end
        
        function plot_graphs(app)
             %% PLots 
             plot1Dheatflux(app.R_1D,app.heat_flux_batch1D,app.str);
             pause(1);
             plotHeatMap(app.X,app.Y,app.heat_flux_batch2D) ;
             pause(1);
             plot_ppl_effects(app.R_1D,app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch);
             pause(1);
             plot_heatmap_injuries(app.X,app.Y,app.P_level1_2d_batch , app.P_level2_2d_batch ,  app.P_level3_2d_batch);
             pause(1);
             
             
            Sim_array = [ app.vessel_volume ;app.initial_mass  ; app.D_max ; app.burning_rate ; app.t_max ; app.fireball_height ;   app.N0;app.ovr_injuries_lv1;app.ovr_injuries_lv2;app.ovr_injuries_lv3];
            app.fx = figure('Name','Overview');
            app.fx.Resize = 'off';
            app.fx.Position(3) = 800; 
            app.fx.Position(4) = 500;
            %fx.ToolBar = 'none';
            %fx.MenuBar = 'none';
            uit = uitable(app.fx,'Data',Sim_array,'Position',[app.fx.Position(1)*0.25  app.fx.Position(2)*0.75 app.fx.Position(3)*0.5 app.fx.Position(4)*0.465]);
            uit.ColumnName = {'Value'};
            uit.RowName = {'Vessel''s volume [m3]','Initial mass [kg]','Fireball''s max diameter [m]','Burning rate [kg/s]','Duration [s]','Fireball elevation [m]','Pop density [ppl/m2]','N° level 1 burns','N° level 2 burns','N° deaths'};
            
            
            if(app.custom_targets_counter > 0) 
            pause(1);
            app.fx2 = figure('Name','Custom Targets') ; 
            app.fx2.Resize = 'off';
            app.fx2.Position(3) = 800; 
            app.fx2.Position(4) = 500;
            uit2 = uitable(app.fx2,'Data',app.custom_targets_results,'Position',[app.fx2.Position(1)*0.1  app.fx2.Position(2)*0.85 app.fx2.Position(3)*0.9 app.fx2.Position(4)*0.465]);
            uit2.ColumnName = {'Target','Distance [m]','Heat flux [kw/m²]','Prob of injury type 1 [%]','Prob of injury type 2 [%]','Prob of death [%]' };
            
       
            end

        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           
         app.f = waitbar(0,'#Step 1/7 # Initializing model','Name','Fireball modelization');
           
         app.read_input_fields() ; 
         app.run_computations() ;
         app.plot_graphs() ;


        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
             
            %            selectedRow = event.Indices(1);
            %            selectedCol = event.Indices(2);
            if (isempty(event.Indices))
                app.current_row = 0 ;
            else
                app.current_row = event.Indices(1);
            end
         %  disp(app.current_row) ;
        end

        % Button pushed function: AddentreeButton
        function AddentreeButtonPushed(app, event)
            %           max_targets = 25 ; 
            %         custom_targets_array = 0 ; 
            %         custom_targets_counter = 0 ; 
            %         current_row = 0 ;
            %         idx = 0 ;

                if ( (app.custom_targets_counter + 1) <= app.max_targets) 
                    app.idx = app.idx + 1 ;
                    app.custom_targets_counter = app.custom_targets_counter + 1 ;
                    temp = [app.idx   ,  app.TargetdistancemEditField.Value];
                    dat = app.UITable.Data;
                    if(isempty(dat))
                        app.UITable.Data=temp  ;                    
                    else
                        app.UITable.Data = [app.UITable.Data ; temp ] ; 
                    end
                else
                     opts = struct('WindowStyle','modal','Interpreter','tex');
                     errordlg(strcat('You can''t add more than  ',num2str(app.max_targets)),'Error',opts);

                end
        end

        % Button pushed function: RemoveentreeButton
        function RemoveentreeButtonPushed(app, event)
             if (app.custom_targets_counter >= 1 && app.current_row>0 && app.current_row<=app.custom_targets_counter )
                  
                dat = app.UITable.Data;
                dat(app.current_row , :) = [] ;
                app.UITable.Data = dat ;
                app.custom_targets_counter = app.custom_targets_counter - 1 ;
                
            end
        end

        % Close request function: FireballUIFigure
        function FireballUIFigureCloseRequest(app, event)
            close all force 
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FireballUIFigure = uifigure;
            app.FireballUIFigure.Name = 'Fireball';
            app.FireballUIFigure.CloseRequestFcn = createCallbackFcn(app, @FireballUIFigureCloseRequest, true);
            app.StartButton = uibutton(app.FireballUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start    >';
            app.TabGroup = uitabgroup(app.FireballUIFigure);
            app.ScenarioTab = uitab(app.TabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.VesselPanel = uipanel(app.ScenarioTab);
            app.VesselPanel.Title = 'Vessel';
            app.Vesselsvolumem3EditFieldLabel = uilabel(app.VesselPanel);
            app.Vesselsvolumem3EditFieldLabel.Text = {'Vessel''s volume'; '(m3)'};
            app.Vesselsvolumem3EditField = uieditfield(app.VesselPanel, 'numeric');
            app.Vesselsvolumem3EditField.Limits = [0.1 Inf];
            app.Vesselsvolumem3EditField.Value = 0.1;
            app.GasfractionEditFieldLabel = uilabel(app.VesselPanel);
            app.GasfractionEditFieldLabel.Text = 'Gas fraction';
            app.GasfractionEditField = uieditfield(app.VesselPanel, 'numeric');
            app.GasfractionEditField.Limits = [0.01 Inf];
            app.GasfractionEditField.Value = 0.2;
            app.GaspressureMPaEditFieldLabel = uilabel(app.VesselPanel);
            app.GaspressureMPaEditFieldLabel.Text = {'Gas pressure'; '[MPa]'};
            app.GaspressureMPaEditField = uieditfield(app.VesselPanel, 'numeric');
            app.GaspressureMPaEditField.Limits = [0.1 Inf];
            app.GaspressureMPaEditField.Value = 1;
            app.FlamablesubstancePanel = uipanel(app.ScenarioTab);
            app.FlamablesubstancePanel.Title = 'Flamable substance';
            app.CombustionheatkjkgEditFieldLabel = uilabel(app.FlamablesubstancePanel);
            app.CombustionheatkjkgEditFieldLabel.Text = {'Combustion heat'; '(kj/kg)'};
            app.CombustionheatkjkgEditField = uieditfield(app.FlamablesubstancePanel, 'numeric');
            app.CombustionheatkjkgEditField.Limits = [1 Inf];
            app.CombustionheatkjkgEditField.Value = 1;
            app.DensityKgm3EditFieldLabel = uilabel(app.FlamablesubstancePanel);
            app.DensityKgm3EditFieldLabel.Text = {'Density'; '[Kg/m3]'};
            app.DensityKgm3EditField = uieditfield(app.FlamablesubstancePanel, 'numeric');
            app.DensityKgm3EditField.Limits = [1 Inf];
            app.DensityKgm3EditField.Value = 1;
            app.AmbiantconditionsPanel = uipanel(app.ScenarioTab);
            app.AmbiantconditionsPanel.Title = 'Ambiant conditions';
            app.Humidityratio01EditFieldLabel = uilabel(app.AmbiantconditionsPanel);
            app.Humidityratio01EditFieldLabel.Text = {'Humidity ratio'; '[0-1]'};
            app.Humidityratio01EditField = uieditfield(app.AmbiantconditionsPanel, 'numeric');
            app.Humidityratio01EditField.Limits = [0 Inf];
            app.SaturationvapourpressurePaEditFieldLabel = uilabel(app.AmbiantconditionsPanel);
            app.SaturationvapourpressurePaEditFieldLabel.Text = {'Saturation vapour pressure'; '[Pa]'};
            app.SaturationvapourpressurePaEditField = uieditfield(app.AmbiantconditionsPanel, 'numeric');
            app.SaturationvapourpressurePaEditField.Limits = [500 Inf];
            app.SaturationvapourpressurePaEditField.Value = 3200;
            app.ThermaleffectsPanel = uipanel(app.ScenarioTab);
            app.ThermaleffectsPanel.Title = 'Thermal effects';
            app.PopulationdensitypersonmEditFieldLabel = uilabel(app.ThermaleffectsPanel);
            app.PopulationdensitypersonmEditFieldLabel.Text = {'Population density'; 'person/m²'; ''};
            app.PopulationdensitypersonmEditField = uieditfield(app.ThermaleffectsPanel, 'numeric');
            app.PopulationdensitypersonmEditField.Limits = [0 Inf];
            app.PopulationdensitypersonmEditField.Tooltip = {'To compute overall injuries/deaths '; 'If not avaiable'; ' leave 0 value'};
            app.PopulationdensitypersonmEditField.Value = 0.05;
            app.BodycoverageimpactofclothesDropDownLabel = uilabel(app.ThermaleffectsPanel);
            app.BodycoverageimpactofclothesDropDownLabel.Text = {'Body coverage'; '(impact of clothes)'};
            app.BodycoverageimpactofclothesDropDown = uidropdown(app.ThermaleffectsPanel);
            app.BodycoverageimpactofclothesDropDown.Items = {'Summer (small coverage)', 'Winter (large coverage)', 'No impact'};
            app.BodycoverageimpactofclothesDropDown.ItemsData = {'0.95', '0.14', '1'};
            app.BodycoverageimpactofclothesDropDown.Value = '0.95';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.Applyasafetyfactorofx2CheckBox = uicheckbox(app.ModelTab);
            app.Applyasafetyfactorofx2CheckBox.Tooltip = {'Over-estimate heatflux values by multiplying x2'};
            app.Applyasafetyfactorofx2CheckBox.Text = '  Apply a safety factor of x2';
            app.ComputationsresolutionDropDownLabel = uilabel(app.ModelTab);
            app.ComputationsresolutionDropDownLabel.Text = 'Computations resolution';
            app.ComputationsresolutionDropDown = uidropdown(app.ModelTab);
            app.ComputationsresolutionDropDown.Items = {'Low (faster)', 'Medium ', 'High (slower)'};
            app.ComputationsresolutionDropDown.ItemsData = {'125', '250', '500'};
            app.ComputationsresolutionDropDown.Value = '250';
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.AddtargetPanel = uipanel(app.TargetsTab);
            app.AddtargetPanel.Title = 'Add target';
            app.TargetdistancemEditFieldLabel = uilabel(app.AddtargetPanel);
            app.TargetdistancemEditFieldLabel.Text = {'Target distance'; '[m]'};
            app.TargetdistancemEditField = uieditfield(app.AddtargetPanel, 'numeric');
            app.AddentreeButton = uibutton(app.AddtargetPanel, 'push');
            app.AddentreeButton.ButtonPushedFcn = createCallbackFcn(app, @AddentreeButtonPushed, true);
            app.AddentreeButton.Text = 'Add entree';
            app.UITable = uitable(app.TargetsTab);
            app.UITable.ColumnName = {'Target 1'; 'Distance [m]'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RemoveentreeButton = uibutton(app.TargetsTab, 'push');
            app.RemoveentreeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveentreeButtonPushed, true);
            app.RemoveentreeButton.Text = 'Remove entree';
            app.DefinecustomtargetsLabel = uilabel(app.TargetsTab);
            app.DefinecustomtargetsLabel.Text = 'Define custom targets';
            app.FireballLabel_2 = uilabel(app.FireballUIFigure);
            app.FireballLabel_2.Text = 'Fireball';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fireball

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FireballUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FireballUIFigure)
        end
    end
end