classdef jetfire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JetfireUIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VesselTab                       matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        VesselpressureMPaEditFieldLabel  matlab.ui.control.Label
        VesselpressureMPaEditField      matlab.ui.control.NumericEditField
        VesseltemperatureKEditFieldLabel  matlab.ui.control.Label
        VesseltemperatureKEditField     matlab.ui.control.NumericEditField
        MolecularweightKgmolEditFieldLabel  matlab.ui.control.Label
        MolecularweightKgmolEditField   matlab.ui.control.NumericEditField
        IsobaricheatcapacityCpKJmolKEditFieldLabel  matlab.ui.control.Label
        IsobaricheatcapacityCpKJmolKEditField  matlab.ui.control.NumericEditField
        CombustionheatKJKgEditFieldLabel  matlab.ui.control.Label
        CombustionheatKJKgEditField     matlab.ui.control.NumericEditField
        MassflowrateKgsEditFieldLabel   matlab.ui.control.Label
        MassflowrateKgsEditField        matlab.ui.control.NumericEditField
        OrificediametermmEditFieldLabel  matlab.ui.control.Label
        OrificediametermmEditField      matlab.ui.control.NumericEditField
        jvEditFieldLabel                matlab.ui.control.Label
        jvEditField                     matlab.ui.control.NumericEditField
        AmbiantconditionsTab            matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        AmbianttemperatureKEditFieldLabel  matlab.ui.control.Label
        AmbianttemperatureKEditField    matlab.ui.control.NumericEditField
        WindvelocitymsEditFieldLabel    matlab.ui.control.Label
        WindvelocitymsEditField         matlab.ui.control.NumericEditField
        AirdensityKgm3EditFieldLabel    matlab.ui.control.Label
        AirdensityKgm3EditField         matlab.ui.control.NumericEditField
        Airmolecualrweightkgm3EditFieldLabel  matlab.ui.control.Label
        Airmolecualrweightkgm3EditField  matlab.ui.control.NumericEditField
        Humidityratio01EditFieldLabel   matlab.ui.control.Label
        Humidityratio01EditField        matlab.ui.control.NumericEditField
        SaturationVapourpressurePaEditFieldLabel  matlab.ui.control.Label
        SaturationVapourpressurePaEditField  matlab.ui.control.NumericEditField
        AmbiantpressureMPaEditFieldLabel  matlab.ui.control.Label
        AmbiantpressureMPaEditField     matlab.ui.control.NumericEditField
        ModelTab                        matlab.ui.container.Tab
        Applyasafetyfactorofx2CheckBox  matlab.ui.control.CheckBox
        ComputationsresolutionDropDownLabel  matlab.ui.control.Label
        ComputationsresolutionDropDown  matlab.ui.control.DropDown
        BodycoverageimpactofclothesDropDownLabel  matlab.ui.control.Label
        BodycoverageimpactofclothesDropDown  matlab.ui.control.DropDown
        CustomtargetsTab                matlab.ui.container.Tab
        DefinecustomtargetsLabel        matlab.ui.control.Label
        AddtargetPanel                  matlab.ui.container.Panel
        TargetdistancemEditFieldLabel   matlab.ui.control.Label
        TargetdistancemEditField        matlab.ui.control.NumericEditField
        AddentreeButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RemoveentreeButton              matlab.ui.control.Button
        JetFireLabel                    matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        %% user data 
        %% vessel : 
        Ps = 0 ; % : initial pressure of vessel - MPa
        Ts  = 0 ;%: initial temperatuer of vessel - K
        d0  = 0 ;%: hole diameter in [mm]
        teta_jv = 0 ;%: [0-90] the angle between the axis of the hole and the horizontal axis % in the direction of the wind (in degrees) 
        mass_flow = 0 % of fuel gas kg/s 
        molecular_weight = 0 ; % of the gas, kg/mol
        gamma = 0 ; 
        Cp = 0 ; % Kj/Kg.  K==> gamma = Cp  / (Cp - R/molecular_weight )
        combustion_heat = 0 ; % of flamable substance - KJ/Kg
        
        %% Ambiant conditions 
        Pa = 0 ; % ambiant pressure in MPa
        Ta = 0 ; % ambiant temperatrue 
        molecular_weight_air = 0 ; % kg/m3
        air_density = 0 ; %  desntity of ambiant aire - kg/m3 
        uw = 0 ; % wind speed - m/s 
        Pow = 0 ; % saturation vapour pressure in the air in Pa
        RH  = 0 ; % humidity ratio in [0-1]
        
        %% Model 
        Resolution  =  0 ; %  125 250 500 
        safety_factor = 0 ; % 1 or 2 
        Fk  = 0 ; % impact of clothes : 1 -> no impact / .95-> summer (small coverage of skin) / % 0.14 -> winter (large coverage)
        
        
        %% computed values 
        mach = 0 ; % mach number
                
        uo = 0 ; % jet velocity at orifice - m/s - u_o=-1 if mass_flow not provided
        To = 0 ; %temperature at orifice - K 
        Po = 0 ; % exit pressure at orifice - MPa
        uj = 0 ; % velocity of the flammable fuel gases in the expanding jet in m/s
        Ds  = 0; %  the diameter of a hypothetical nozzle through which flows fuel in meters 
        Lb  = 0 ; % length of the flame  in meters 
        Lbo = 0 ;  
        alpha  = 0; % in degrees- angle between the axis of the hole and the axis of the flame
        b = 0 ; % lift-off from ground (meters)
        phi = 0 ; % angle between orifice axis &  center of frustum tip - degrees 
        Rl = 0 ; % length of the frustum - in meters 
        W1 = 0 ; %  frustum base width - m 
        W2 = 0 ; %   frustum tip width - m 
        A  = 0 ; %  surface area of frustum including end discs, m² 
        SEP  = 0 ; % Surface Emitting Power - kW/m²
        
        % 
        R_1D = 0 ; 
        heat_flux_batch1D = 0 ;
        heatflux_from_distance_fit = 0 ; 
        distance_from_heatflux_fit = 0 ;
        gof = 0 ;
        interpolation_str = 0 ;
        P_level1_1d_batch = 0 ;P_level2_1d_batch = 0 ; P_level3_1d_batch = 0 ;

        % figures 
          %% Waitbar 
        f = 0 ;
        uf = 0 ;
        f4 = 0 ;
        fx2 = 0 ;
        fx = 0 ;
        
        % custom targets 
        max_targets = 25 ; 
        custom_targets_array = 0 ; 
        custom_targets_results = 0 ;
        custom_targets_counter = 0 ; 
        current_row = 0 ;
        idx = 0 ;


    end


    methods (Access = private)
        
        function read_fields(app)
            
           app.Ps = app.VesselpressureMPaEditField.Value ;
           app.Ts = app.VesseltemperatureKEditField.Value;
           app.molecular_weight =  app.MolecularweightKgmolEditField.Value ;
           app.Cp =  app.IsobaricheatcapacityCpKJmolKEditField.Value;
           app.combustion_heat =  app.CombustionheatKJKgEditField.Value ;
           app.mass_flow = app.MassflowrateKgsEditField.Value ; 
           app.d0 = app.OrificediametermmEditField.Value ; 
           app.teta_jv = app.jvEditField.Value ; 
                
           app.Ta = app.AmbianttemperatureKEditField.Value ; 
           app.uw = app.WindvelocitymsEditField.Value ;
           app.air_density = app.AirdensityKgm3EditField.Value ;
           app.molecular_weight_air = app.Airmolecualrweightkgm3EditField.Value ;
           app.RH = app.Humidityratio01EditField.Value ;
           app.Pow = app.SaturationVapourpressurePaEditField.Value ;
           app.Pa = app.AmbiantpressureMPaEditField.Value ;
                
                 
           if (app.Applyasafetyfactorofx2CheckBox.Value)
                app.safety_factor = 2 ;
           else
                app.safety_factor = 1 ; 
           end 
           
           app.Resolution  =  str2double(app.ComputationsresolutionDropDown.Value); % drpdown
           app.Fk = str2double(app.BodycoverageimpactofclothesDropDown.Value); 
           
           disp(app.Cp)
           app.Cp = app.Cp * 10^3 ; 
           rcst = 8.314; 
           app.gamma = app.Cp / (  app.Cp - rcst/app.molecular_weight );
           
           app.custom_targets_array = app.UITable.Data ; 
                
            
        end
        
        function run_computations(app)
            
        %% Computations
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.17,app.f,'#Step 2/6 #Running initial computations');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
                [app.To,app.Po,app.uo] = exit_conditions_computation(app.Ps,app.Ts,app.Pa,app.gamma,app.molecular_weight,app.mass_flow,app.d0) ;
                
                a1 = app.Ps / app.Pa ; 
                a2 = ( (app.gamma+1)/2 )^(app.gamma/(app.gamma-1)); 
                if (a1>=a2)
                   app.mach = supersonic_mach_number_computation(app.Po,app.Pa,app.gamma) ;                      
                else
                   app.mach = subsonic_mach_number_computation(app.uo,app.gamma);                           
                end
                                       
                app.uj = flow_velocity_computation(app.mach , app.gamma , app.To, app.molecular_weight) ;
                
               
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.33,app.f,'#Step 3/6 #Computing geometric properties');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
                [app.Ds,app.Lb,app.Lbo,app.alpha,app.b,app.phi,app.Rl,app.W1,app.W2,app.A] = jetfire_goemetric_properties_computation(app.molecular_weight_air,app.mass_flow,app.air_density,app.uj,app.uw,app.teta_jv,app.molecular_weight,app.To,app.Ta);
                %%
                
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.49,app.f,'#Step 4/6 #Computing heatflux');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
                app.SEP = SEP_computation(app.uj,app.combustion_heat, app.mass_flow , app.A ); 
                
                [app.R_1D,app.heat_flux_batch1D] = batch_heat_flux_computation_1D(app.SEP,app.Rl,app.Resolution, app.b,app.teta_jv, app.alpha, app.Pow,app.RH ,app.W1,app.W2 );
                 if(app.safety_factor > 1)
                    app.heat_flux_batch1D = app.safety_factor.*app.heat_flux_batch1D ;
                 end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.65,app.f,'#Step 5/6 #Fitting model');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                 %%
                 % curve fitting 
                 [app.heatflux_from_distance_fit, app.gof] = fit_get_heatflux(app.R_1D, app.heat_flux_batch1D);
                 app.interpolation_str ={formula(app.heatflux_from_distance_fit),' parameters resp : ',num2str(coeffvalues(app.heatflux_from_distance_fit)),'R^2 = ',app.gof.rsquare };
                 [app.distance_from_heatflux_fit] = fit_get_distance(app.heat_flux_batch1D, app.R_1D);
                
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        waitbar(.81,app.f,'#Step 6/6 #Computing thermal effects');
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
                 [app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch ] = batch_ppl_effect_computation_1D(app.Fk,app.R_1D,  app.W1,app.W2 , app.distance_from_heatflux_fit  ,app.heatflux_from_distance_fit); 
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
                if (app.custom_targets_counter>0 )
                    waitbar(0.97,app.f,'Processing custom targets');
                    
                    w = size(app.custom_targets_array,1); 
                    
                    app.custom_targets_results = zeros(w ,6);
                    
                    
                        for i=1:w
                            F_12_trgt = v_factor(app.b,app.teta_jv,app.custom_targets_array(i,2),app.alpha,app.Rl,app.W1,app.W2);
                            trs_trgt = transmissivity(app.Pow,app.RH,app.custom_targets_array(i,2),app.W1,app.W2);
                            hf = single_heatflux_computation(app.SEP,F_12_trgt,trs_trgt);
                            [a1,a2, a3] = ppl_effect_computation(app.Fk , app.custom_targets_array(i,2), (app.W1+app.W2)/2 , app.distance_from_heatflux_fit  ,app.heatflux_from_distance_fit,hf);
                            app.custom_targets_results(i,:) = [floor(app.custom_targets_array(i,1)) app.custom_targets_array(i,2) app.safety_factor*hf 100*a1 100*a2 100*a3] ;
                        end            
                end
                
                
                waitbar(1,app.f,'Finishing');
                pause(1)
                close(app.f)
                
        end


        function plot_results(app)
            
            plot1Dheatflux(app.R_1D,app.heat_flux_batch1D,app.interpolation_str);
            pause(1);
            plot_ppl_effects(app.R_1D,app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch);
            pause(1);
            
            Sim_array = [app.To ; app.Po ; app.uo ; app.uj ; app.mach];
            app.fx = uifigure('Name','Flow properties');
            %app.fx.Resize = 'off';
            app.fx.Position(3:4) = [400 400];
            %app.fx.Position(4) = 500;
            %fx.ToolBar = 'none';
            %fx.MenuBar = 'none';
            uit2 = uitable(app.fx);
            uit2.Data = Sim_array;
            uit2.ColumnName = {'Value'};
            uit2.RowName =   {'Temp at orifice[k] ' , 'Pressure at orifice[MPa]' , 'Jet velocity orifice[m/s]','Expanding jet velocity[m/s]','Jet mach number'};
            
                                   
            if(app.custom_targets_counter > 0) 
            pause(1);
            app.fx2 = figure('Name','Custom Targets') ; 
            app.fx2.Resize = 'off';
            app.fx2.Position(3) = 800; 
            app.fx2.Position(4) = 500;
            uit2 = uitable(app.fx2,'Data',app.custom_targets_results,'Position',[app.fx2.Position(1)*0.1  app.fx2.Position(2)*0.85 app.fx2.Position(3)*0.9 app.fx2.Position(4)*0.465]);
            uit2.ColumnName = {'Target','Distance [m]','Heat flux [kw/m²]','Prob of injury type 1 [%]','Prob of injury type 2 [%]','Prob of death [%]' };
            end


            %% Geometry
            pause(1);
            app.uf = uifigure('Name','Flame Geometry');
            app.uf.Position(3:4) = [375 360];
            uit = uitable(app.uf);
            uit.RowName = {'Area [m²]','Flame length / L_b [m]','Frustum length / R_l [m]','Lift off / b [m] ','W1 [m]','W2','nozzle diameter /Ds [m]','α [°]','φ [°]'};
            uit.ColumnName = {'Values'};
            data_array = [app.A;app.Lb;app.Rl;app.b;app.W1;app.W2;app.Ds;app.alpha;app.phi];
            uit.Data = data_array;
            %uit.Position(3) = 375;
            pause(2);
            app.f4 = figure ;
            I = imread('Jet-fire-solid-flame-model_W640.jpg');
            imshow(I); 


        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.f = waitbar(0,'#Step 1/6 # Initializing model','Name','Jet fire modelization');
            
            app.read_fields();
            app.run_computations();
            app.plot_results();
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
             
            if (isempty(event.Indices))
                app.current_row = 0 ;
            else
                app.current_row = event.Indices(1);
            end
         %  disp(app.current_row) ;
        end

        % Button pushed function: AddentreeButton
        function AddentreeButtonPushed(app, event)
              %           max_targets = 25 ; 
            %         custom_targets_array = 0 ; 
            %         custom_targets_counter = 0 ; 
            %         current_row = 0 ;
            %         idx = 0 ;

                if ( (app.custom_targets_counter + 1) <= app.max_targets) 
                    app.idx = app.idx + 1 ;
                    app.custom_targets_counter = app.custom_targets_counter + 1 ;
                    temp = [app.idx   ,  app.TargetdistancemEditField.Value];
                    dat = app.UITable.Data;
                    if(isempty(dat))
                        app.UITable.Data=temp  ;                    
                    else
                        app.UITable.Data = [app.UITable.Data ; temp ] ; 
                    end
                else
                     opts = struct('WindowStyle','modal','Interpreter','tex');
                     errordlg(strcat('You can''t add more than  ',num2str(app.max_targets)),'Error',opts);

                end
        end

        % Button pushed function: RemoveentreeButton
        function RemoveentreeButtonPushed(app, event)
                 
                if (app.custom_targets_counter >= 1 && app.current_row>0 && app.current_row<=app.custom_targets_counter )
                  
                dat = app.UITable.Data;
                dat(app.current_row , :) = [] ;
                app.UITable.Data = dat ;
                app.custom_targets_counter = app.custom_targets_counter - 1 ;
                 
                end
                
        end

        % Close request function: JetfireUIFigure
        function JetfireUIFigureCloseRequest(app, event)
            close all force 
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.JetfireUIFigure = uifigure;
            app.JetfireUIFigure.Name = 'Jetfire';
            app.JetfireUIFigure.CloseRequestFcn = createCallbackFcn(app, @JetfireUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.JetfireUIFigure);
            app.VesselTab = uitab(app.TabGroup);
            app.VesselTab.Title = 'Vessel';
            app.Panel_2 = uipanel(app.VesselTab);
            app.VesselpressureMPaEditFieldLabel = uilabel(app.Panel_2);
            app.VesselpressureMPaEditFieldLabel.Text = {'Vessel pressure'; '(MPa)'};
            app.VesselpressureMPaEditField = uieditfield(app.Panel_2, 'numeric');
            app.VesselpressureMPaEditField.Limits = [0.1 Inf];
            app.VesselpressureMPaEditField.Value = 1;
            app.VesseltemperatureKEditFieldLabel = uilabel(app.Panel_2);
            app.VesseltemperatureKEditFieldLabel.Text = {'Vessel temperature'; '(K)'};
            app.VesseltemperatureKEditField = uieditfield(app.Panel_2, 'numeric');
            app.VesseltemperatureKEditField.Limits = [0.1 Inf];
            app.VesseltemperatureKEditField.Value = 298;
            app.MolecularweightKgmolEditFieldLabel = uilabel(app.Panel_2);
            app.MolecularweightKgmolEditFieldLabel.Text = {'Molecular weight'; '(Kg/mol)'};
            app.MolecularweightKgmolEditField = uieditfield(app.Panel_2, 'numeric');
            app.MolecularweightKgmolEditField.Limits = [0.001 Inf];
            app.MolecularweightKgmolEditField.Value = 0.0186;
            app.IsobaricheatcapacityCpKJmolKEditFieldLabel = uilabel(app.Panel_2);
            app.IsobaricheatcapacityCpKJmolKEditFieldLabel.Text = {'Isobaric heat capacity Cp'; '(KJ/mol/K)'};
            app.IsobaricheatcapacityCpKJmolKEditField = uieditfield(app.Panel_2, 'numeric');
            app.IsobaricheatcapacityCpKJmolKEditField.Limits = [1 Inf];
            app.IsobaricheatcapacityCpKJmolKEditField.Value = 1;
            app.CombustionheatKJKgEditFieldLabel = uilabel(app.Panel_2);
            app.CombustionheatKJKgEditFieldLabel.Text = {'Combustion heat'; '(KJ/Kg)'};
            app.CombustionheatKJKgEditField = uieditfield(app.Panel_2, 'numeric');
            app.CombustionheatKJKgEditField.Limits = [0.1 Inf];
            app.CombustionheatKJKgEditField.Value = 1;
            app.MassflowrateKgsEditFieldLabel = uilabel(app.Panel_2);
            app.MassflowrateKgsEditFieldLabel.Text = {'Mass flow rate'; '(Kg/s)'};
            app.MassflowrateKgsEditField = uieditfield(app.Panel_2, 'numeric');
            app.MassflowrateKgsEditField.Limits = [0.1 Inf];
            app.MassflowrateKgsEditField.Value = 0.1;
            app.OrificediametermmEditFieldLabel = uilabel(app.Panel_2);
            app.OrificediametermmEditFieldLabel.Text = {'Orifice diameter'; '(mm)'};
            app.OrificediametermmEditField = uieditfield(app.Panel_2, 'numeric');
            app.OrificediametermmEditField.Limits = [1 Inf];
            app.OrificediametermmEditField.Tooltip = {'if not available : -1'; ''};
            app.OrificediametermmEditField.Value = 1;
            app.jvEditFieldLabel = uilabel(app.Panel_2);
            app.jvEditFieldLabel.Text = {'θ jv '; '(°)'};
            app.jvEditField = uieditfield(app.Panel_2, 'numeric');
            app.jvEditField.Limits = [0 90];
            app.jvEditField.Tooltip = {'[0-90] the angle between the axis of the hole and the'; 'horizontal axis  in the direction of the wind (in degrees) '};
            app.jvEditField.Value = 45;
            app.AmbiantconditionsTab = uitab(app.TabGroup);
            app.AmbiantconditionsTab.Title = 'Ambiant conditions';
            app.Panel = uipanel(app.AmbiantconditionsTab);
            app.AmbianttemperatureKEditFieldLabel = uilabel(app.Panel);
            app.AmbianttemperatureKEditFieldLabel.Text = {'Ambiant temperature '; '(K)'};
            app.AmbianttemperatureKEditField = uieditfield(app.Panel, 'numeric');
            app.AmbianttemperatureKEditField.Limits = [1 323];
            app.AmbianttemperatureKEditField.Value = 298;
            app.WindvelocitymsEditFieldLabel = uilabel(app.Panel);
            app.WindvelocitymsEditFieldLabel.Text = {'Wind velocity'; '(m/s)'};
            app.WindvelocitymsEditField = uieditfield(app.Panel, 'numeric');
            app.WindvelocitymsEditField.Limits = [0 Inf];
            app.AirdensityKgm3EditFieldLabel = uilabel(app.Panel);
            app.AirdensityKgm3EditFieldLabel.Text = {'Air density'; '(Kg/m3)'};
            app.AirdensityKgm3EditField = uieditfield(app.Panel, 'numeric');
            app.AirdensityKgm3EditField.Limits = [1 3];
            app.AirdensityKgm3EditField.Value = 1.225;
            app.Airmolecualrweightkgm3EditFieldLabel = uilabel(app.Panel);
            app.Airmolecualrweightkgm3EditFieldLabel.Text = {'Air molecualr weight'; '(kg/m3)'};
            app.Airmolecualrweightkgm3EditField = uieditfield(app.Panel, 'numeric');
            app.Airmolecualrweightkgm3EditField.Limits = [0.01 Inf];
            app.Airmolecualrweightkgm3EditField.Value = 0.029;
            app.Humidityratio01EditFieldLabel = uilabel(app.Panel);
            app.Humidityratio01EditFieldLabel.Text = {'Humidity ratio'; '[0-1]'};
            app.Humidityratio01EditField = uieditfield(app.Panel, 'numeric');
            app.Humidityratio01EditField.Limits = [0 1];
            app.Humidityratio01EditField.Value = 0.7;
            app.SaturationVapourpressurePaEditFieldLabel = uilabel(app.Panel);
            app.SaturationVapourpressurePaEditFieldLabel.Text = {'Saturation Vapour'; 'pressure'; '(Pa)'};
            app.SaturationVapourpressurePaEditField = uieditfield(app.Panel, 'numeric');
            app.SaturationVapourpressurePaEditField.Limits = [500 100000];
            app.SaturationVapourpressurePaEditField.Value = 2320;
            app.AmbiantpressureMPaEditFieldLabel = uilabel(app.Panel);
            app.AmbiantpressureMPaEditFieldLabel.Text = {'Ambiant pressure'; '(MPa)'};
            app.AmbiantpressureMPaEditField = uieditfield(app.Panel, 'numeric');
            app.AmbiantpressureMPaEditField.Limits = [0.1 Inf];
            app.AmbiantpressureMPaEditField.Value = 0.1;
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.Applyasafetyfactorofx2CheckBox = uicheckbox(app.ModelTab);
            app.Applyasafetyfactorofx2CheckBox.Tooltip = {'Over-estimate heatflux values by multiplying x2'};
            app.Applyasafetyfactorofx2CheckBox.Text = '  Apply a safety factor of x2';
            app.ComputationsresolutionDropDownLabel = uilabel(app.ModelTab);
            app.ComputationsresolutionDropDownLabel.Text = 'Computations resolution';
            app.ComputationsresolutionDropDown = uidropdown(app.ModelTab);
            app.ComputationsresolutionDropDown.Items = {'Low (faster)', 'Medium ', 'High (slower)'};
            app.ComputationsresolutionDropDown.ItemsData = {'125', '250', '500'};
            app.ComputationsresolutionDropDown.Value = '250';
            app.BodycoverageimpactofclothesDropDownLabel = uilabel(app.ModelTab);
            app.BodycoverageimpactofclothesDropDownLabel.Text = {'Body coverage'; '(impact of clothes)'};
            app.BodycoverageimpactofclothesDropDown = uidropdown(app.ModelTab);
            app.BodycoverageimpactofclothesDropDown.Items = {'Summer (small coverage)', 'Winter (large coverage)', 'No impact'};
            app.BodycoverageimpactofclothesDropDown.ItemsData = {'0.95', '0.14', '1'};
            app.BodycoverageimpactofclothesDropDown.Value = '0.95';
            app.CustomtargetsTab = uitab(app.TabGroup);
            app.CustomtargetsTab.Title = 'Custom targets';
            app.DefinecustomtargetsLabel = uilabel(app.CustomtargetsTab);
            app.DefinecustomtargetsLabel.Text = 'Define custom targets';
            app.AddtargetPanel = uipanel(app.CustomtargetsTab);
            app.AddtargetPanel.Title = 'Add target';
            app.TargetdistancemEditFieldLabel = uilabel(app.AddtargetPanel);
            app.TargetdistancemEditFieldLabel.Text = {'Target distance'; '[m]'};
            app.TargetdistancemEditField = uieditfield(app.AddtargetPanel, 'numeric');
            app.AddentreeButton = uibutton(app.AddtargetPanel, 'push');
            app.AddentreeButton.ButtonPushedFcn = createCallbackFcn(app, @AddentreeButtonPushed, true);
            app.AddentreeButton.Text = 'Add entree';
            app.UITable = uitable(app.CustomtargetsTab);
            app.UITable.ColumnName = {'Target'; 'Distance [m]'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RemoveentreeButton = uibutton(app.CustomtargetsTab, 'push');
            app.RemoveentreeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveentreeButtonPushed, true);
            app.RemoveentreeButton.Text = 'Remove entree';
            app.JetFireLabel = uilabel(app.JetfireUIFigure);
            app.JetFireLabel.Text = 'Jet Fire';
            app.StartButton = uibutton(app.JetfireUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start    >';
        end
    end

    methods (Access = public)

        % Construct app
        function app = jetfire

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JetfireUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JetfireUIFigure)
        end
    end
end