classdef pool_fire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PoolfireUIFigure                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ScenarioTab                     matlab.ui.container.Tab
        SpillagePanel                   matlab.ui.container.Panel
        TotalVolumem3EditFieldLabel     matlab.ui.control.Label
        TotalVolumem3EditField          matlab.ui.control.NumericEditField
        PooldiametermEditFieldLabel     matlab.ui.control.Label
        PooldiametermEditField          matlab.ui.control.NumericEditField
        ThermaleffectsPanel             matlab.ui.container.Panel
        PopulationdensitypersonmEditFieldLabel  matlab.ui.control.Label
        PopulationdensitypersonmEditField  matlab.ui.control.NumericEditField
        BodycoverageimpactofclothesDropDownLabel  matlab.ui.control.Label
        BodycoverageimpactofclothesDropDown  matlab.ui.control.DropDown
        ThermodynamicpropertiesTab      matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        HeatofvaporizationKjKgLabel     matlab.ui.control.Label
        HeatofvaporizationKjKgEditField  matlab.ui.control.NumericEditField
        HeatofcombustionKjKgEditFieldLabel  matlab.ui.control.Label
        HeatofcombustionKjKgEditField   matlab.ui.control.NumericEditField
        SpecificheatcapacityKjKgKEditFieldLabel  matlab.ui.control.Label
        SpecificheatcapacityKjKgKEditField  matlab.ui.control.NumericEditField
        RadiationfractionEditFieldLabel  matlab.ui.control.Label
        RadiationfractionEditField      matlab.ui.control.NumericEditField
        DensityKgm3EditFieldLabel       matlab.ui.control.Label
        DensityKgm3EditField            matlab.ui.control.NumericEditField
        BoilingtemperatureKLabel        matlab.ui.control.Label
        BoilingtemperatureKEditField    matlab.ui.control.NumericEditField
        AmbiantconditionsTab            matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        AmbianttemperatureKEditFieldLabel  matlab.ui.control.Label
        AmbianttemperatureKEditField    matlab.ui.control.NumericEditField
        WindvelocitymsEditFieldLabel    matlab.ui.control.Label
        WindvelocitymsEditField         matlab.ui.control.NumericEditField
        AirdensityKgm3EditFieldLabel    matlab.ui.control.Label
        AirdensityKgm3EditField         matlab.ui.control.NumericEditField
        AirviscosityuPasEditFieldLabel  matlab.ui.control.Label
        AirviscosityuPasEditField       matlab.ui.control.NumericEditField
        Humidityratio01EditFieldLabel   matlab.ui.control.Label
        Humidityratio01EditField        matlab.ui.control.NumericEditField
        SaturationVapourpressurePaEditFieldLabel  matlab.ui.control.Label
        SaturationVapourpressurePaEditField  matlab.ui.control.NumericEditField
        ModelparametersTab              matlab.ui.container.Tab
        ComputationsresolutionDropDownLabel  matlab.ui.control.Label
        ComputationsresolutionDropDown  matlab.ui.control.DropDown
        Applyasafetyfactorofx2CheckBox  matlab.ui.control.CheckBox
        Panel_5                         matlab.ui.container.Panel
        EquivalentburnrateKgsmEditFieldLabel  matlab.ui.control.Label
        EquivalentburnrateKgsmEditField  matlab.ui.control.NumericEditField
        kbetacoefficientEditFieldLabel  matlab.ui.control.Label
        kbetacoefficientEditField       matlab.ui.control.NumericEditField
        ModelforburningrateDropDownLabel  matlab.ui.control.Label
        ModelforburningrateDropDown     matlab.ui.control.DropDown
        ModelforSurfaceemittingpowerDropDownLabel  matlab.ui.control.Label
        ModelforSurfaceemittingpowerDropDown  matlab.ui.control.DropDown
        TargetsTab                      matlab.ui.container.Tab
        DefinecustomtargetsLabel        matlab.ui.control.Label
        AddtargetPanel                  matlab.ui.container.Panel
        TargetdistancemEditFieldLabel   matlab.ui.control.Label
        TargetdistancemEditField        matlab.ui.control.NumericEditField
        TargetelevationfromgroundmLabel  matlab.ui.control.Label
        TargetelevationfromgroundmEditField  matlab.ui.control.NumericEditField
        AddentreeButton                 matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        RemoveentreeButton              matlab.ui.control.Button
        PoolfireLabel                   matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        RelevanceButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
                
        % creating variables : 
        
        %% thermo properties          
        eq_burn_rate = 0 ; % kg/m2/s
        k_beta = 0 ; % 1/m 
        hyd_type = 1 ;  % liq gas = 1  - liquid = 2 
        sep_model = 0 ; % 1 : Mudan-Croce  2 : Hagglund & Person
        
        vap_heat = -1 ;      % Kj/Kg
        comb_heat= -1;       % Kj/Kg
        spec_heat_cap = -1 ; % kj/kg/k
        T_boiling = -1 ; % Kelvins 
        h_desntity = 0 ;
        radiation_fraction = 0.2 ; % [0-1]
             
        %% Scneario 
        pool_diameter = 0 ; % m 
        Fk = 1 ;  % body coverage : 0.14 winter / 0.95 summer / 1 no effect
        V = 0 ;   % Volume  in m3
        N0 = 0 ; % ppl desnsity
        
        %% Ambiant conditions : 
        T_ambiant = 298 ; % Kelvins 
        air_density = 0 ; % kg/m3 
        air_viscosity = 0 ; % uPa.s
        RH = 0 ; % humidity fraction [0-1]
        P_ow = 0 ; % saturation vapor pressure in Pa ;
        wind_vel = 0 ; % m/s 
        
        %% Model        
        Resolution = 250 ; % 125 250 500
        apply_safety_factor = 1 ; % 1:no  2:yes 
        
        
        %% Waitbar 
        f = 0 ;
        %% table 
        fx = 0 ;
        %% table 2 
        fx2 = 0 ;

                
        %% Model variables initiation : 
        wind_vel_sharp = 0 ; 
        flame_len = 0 ; % m 
        burning_rate = 0 ; % kg/s
        burning_time =  0; % minutes
        SEP = 0 ;  % Surface emitting power - kw/m2
        tilt = 0 ; % degrees 
        F = 0; % view factor
        X_d = 0 ; % distance from center of fire
        ATM_transmissivity = 0 ; % transmissivity ratio
        heat_flux = 0 ; % kw/m2
        X = 0 ; 
        Y = 0 ;
        % heat flux 
        heat_flux_batch2D = 0 ; % 2D mesh 
        R_1D = 0 ; 
        heat_flux_batch1D = 0 ; 
        fit_R_f_heatflux = 0 ;
        fit_heatflux_f_R = 0 ; 
        str = 0 ;
        % effetcs :
        P_level1_1d_batch = 0 ; 
        P_level2_1d_batch = 0 ; 
        P_level3_1d_batch = 0 ;
        P_level1_2d_batch= 0; 
        P_level2_2d_batch= 0; 
        P_level3_2d_batch = 0;
        ovr_injuries_lv1 = 0 ; 
        ovr_injuries_lv2 = 0 ;
        ovr_injuries_lv3 = 0 ;
        % Custom Targets array 
        max_targets = 25 ; 
        custom_targets_array = 0 ; 
        custom_targets_results = 0 ;
        custom_targets_counter = 0 ; 
        current_row = 0 ;
        idx = 0 ;

    end
    
    methods (Access = private)
        
        function read_input_fields(app)
            
           app.V=  app.TotalVolumem3EditField.Value;
           app.pool_diameter = app.PooldiametermEditField.Value;
            
           app.N0 = app.PopulationdensitypersonmEditField.Value;
           app.Fk = str2double(app.BodycoverageimpactofclothesDropDown.Value);  
            
           app.hyd_type = str2num(app.ModelforburningrateDropDown.Value);  
           app.vap_heat = app.HeatofvaporizationKjKgEditField.Value;
           app.comb_heat = app.HeatofcombustionKjKgEditField.Value;
           app.spec_heat_cap = app.SpecificheatcapacityKjKgKEditField.Value;
           app.radiation_fraction =  app.RadiationfractionEditField.Value;
           app.h_desntity =  app.DensityKgm3EditField.Value;
           app.T_boiling =  app.BoilingtemperatureKEditField.Value;
 
          if (app.hyd_type == 2)              
              app.eq_burn_rate =   app.EquivalentburnrateKgsmEditField.Value;
              app.k_beta =  app.kbetacoefficientEditField.Value;              
          end
          app.sep_model = str2num(app.ModelforSurfaceemittingpowerDropDown.Value);  
            
          app.T_ambiant =  app.AmbianttemperatureKEditField.Value;
          app.wind_vel = app.WindvelocitymsEditField.Value;
          app.air_density =  app.AirdensityKgm3EditField.Value;
          app.air_viscosity =  app.AirviscosityuPasEditField.Value;
          app.RH =  app.Humidityratio01EditField.Value;
          app.P_ow = app.SaturationVapourpressurePaEditField.Value;
            
          app.Resolution = str2num( app.ComputationsresolutionDropDown.Value); % dropdown
          
          % if condition
         if app.Applyasafetyfactorofx2CheckBox.Value
            app.apply_safety_factor = 2 ;
         else
            app.apply_safety_factor = 1 ; 
         end  
         
         app.custom_targets_array = app.UITable.Data ; 
            
            
        end
        
        function run_computations(app)
            %% Computations
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            waitbar(.15,app.f,'Running initial computations');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (app.hyd_type == 1 )

                app.burning_rate = burn_rate_liq_gas(app.vap_heat,app.comb_heat,app.spec_heat_cap,app.T_boiling,app.T_ambiant);
                
            elseif (app.hyd_type == 2)
                           
                app.burning_rate = burn_rate_liq_hydrocarbon(app.eq_burn_rate, app.k_beta, app.pool_diameter );

            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.burning_time = ceil(burning_time_computation(app.V,app.h_desntity,app.burning_rate,app.pool_diameter)) ;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (app.wind_vel == 0) 
                app.flame_len = flame_len_no_wind ( app.pool_diameter , app.air_density , app.burning_rate  );                
            elseif (app.wind_vel > 0) 
                app.flame_len = flame_len_wind(app.pool_diameter,app.air_density,app.burning_rate,app.wind_vel) ;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (app.sep_model == 1) 
                app.SEP = SEP_mudan(app.pool_diameter);
            elseif (app.sep_model == 2) 
                app.SEP = SEP_std(app.radiation_fraction,app.flame_len,app.pool_diameter,app.burning_rate,app.comb_heat);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.tilt = tilt_computation(app.wind_vel,app.pool_diameter,app.air_density,app.air_viscosity);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            waitbar(.30,app.f,'Computing heatflux');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [app.R_1D,app.heat_flux_batch1D] = batch_heat_flux_computation_1D(app.pool_diameter,app.Resolution,app.tilt,app.flame_len,app.RH,app.P_ow,app.SEP);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if ( app.apply_safety_factor > 1)
                app.heat_flux_batch1D = app.heat_flux_batch1D .* app.apply_safety_factor;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            waitbar(.45,app.f,'Fitting model');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % curve fitting  
            app.fit_R_f_heatflux = fit_distance_to_heatflux(app.heat_flux_batch1D,app.R_1D,0) ;
            [app.fit_heatflux_f_R , gof]= fit_heatflux_to_distance(app.R_1D,app.heat_flux_batch1D,0) ; 
            app.str ={formula(app.fit_heatflux_f_R),' parameters resp : ',num2str(coeffvalues(app.fit_heatflux_f_R)),'R^2 = ',gof.rsquare };
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Effetcs
            waitbar(.6,app.f,'Computing thermal effects');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch ] = batch_ppl_effect_computation_1D(app.Fk,app.R_1D,  app.pool_diameter , app.fit_R_f_heatflux  ,app.fit_heatflux_f_R);
            if (app.N0 > 0)
             [app.ovr_injuries_lv1,app.ovr_injuries_lv2,app.ovr_injuries_lv3] = overall_effects_computation(app.N0,app.pool_diameter,app.R_1D,app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch,app.fit_R_f_heatflux) ;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if(app.wind_vel == 0) 
                %%
                waitbar(.75,app.f,'Generating 2D heatmap');
                %%
                [app.X , app.Y , app.heat_flux_batch2D] = batch_heat_flux_computation_2D(app.pool_diameter,app.Resolution,app.tilt,app.flame_len,app.RH,app.P_ow,app.SEP);
                %%
                if ( app.apply_safety_factor > 1)
                app.heat_flux_batch2D = app.heat_flux_batch2D .* app.apply_safety_factor;
                end
                if(app.N0>0)
                    waitbar(.75,app.f,'Generating 2D thermal effects map');
                    %%
                    [app.P_level1_2d_batch , app.P_level2_2d_batch ,  app.P_level3_2d_batch] = batch_ppl_effect_computation_2D(app.Fk, app.X,app.Y,app.pool_diameter , app.fit_R_f_heatflux  ,app.fit_heatflux_f_R);
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (app.custom_targets_counter>0 )
            waitbar(0.9,app.f,'Processing custom targets');
            
            w = size(app.custom_targets_array,1); 
            
            app.custom_targets_results = zeros(w ,7);
             
            
                for i=1:w
                    hf = single_target_heatflux (app.pool_diameter,app.tilt,app.flame_len,app.RH,app.P_ow,app.SEP , app.custom_targets_array(i,2) ,  app.custom_targets_array(i,3));
                    [a1,a2, a3] = ppl_effect_computation(app.Fk , app.custom_targets_array(i,2)  , app.pool_diameter , app.fit_R_f_heatflux  ,app.fit_heatflux_f_R,hf);
                    app.custom_targets_results(i,:) = [floor(app.custom_targets_array(i,1)) app.custom_targets_array(i,2) app.custom_targets_array(i,3) app.apply_safety_factor*hf 100*a1 100*a2 100*a3] ;
                end


            end            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            waitbar(1,app.f,'Finishing');
            pause(1)
            close(app.f)

 
        end
        
        function plot_graphs(app)            
 
             plot1Dheatflux(app.R_1D,app.heat_flux_batch1D,app.wind_vel,app.str) ;
             pause(1);
             plot_ppl_effects(app.R_1D,app.P_level1_1d_batch,app.P_level2_1d_batch, app.P_level3_1d_batch);
             pause(1);
             if(app.wind_vel == 0)
                 plot_heatmap_injuries(app.X,app.Y,app.P_level1_2d_batch , app.P_level2_2d_batch ,  app.P_level3_2d_batch); 
                 plotHeatMap(app.X,app.Y,app.heat_flux_batch2D,app.wind_vel) ;
             end
            pause(1);
            Sim_array = [app.T_ambiant ; app.wind_vel ; 100*app.RH ; app.pool_diameter ; app.burning_rate ; app.flame_len ; app.burning_time ;  app.N0;app.ovr_injuries_lv1;app.ovr_injuries_lv2;app.ovr_injuries_lv3];
            app.fx = figure('Name','Overview');
            app.fx.Resize = 'off';
            app.fx.Position(3) = 800; 
            app.fx.Position(4) = 500;
            %fx.ToolBar = 'none';
            %fx.MenuBar = 'none';
            uit = uitable(app.fx,'Data',Sim_array,'Position',[app.fx.Position(1)*0.5  app.fx.Position(2)*0.75 app.fx.Position(3)*0.44 app.fx.Position(4)*0.465]);
            uit.ColumnName = {'Value'};
            uit.RowName = {'Ambiant temperature [k]','Wind velocity [m/s]','Humidity','Pool diameter [m]','Burning rate [kg/s]','Flame height [m]','Burning time [mn]','Pop density [ppl/m2]','N° level 1 burns','N° level 2 burns','N° deaths'};
            
            if(app.custom_targets_counter > 0) 
            app.fx2 = figure('Name','Custom Targets') ; 
            app.fx2.Resize = 'off';
            app.fx2.Position(3) = 800; 
            app.fx2.Position(4) = 500;
            uit2 = uitable(app.fx2,'Data',app.custom_targets_results,'Position',[app.fx2.Position(1)*0.1  app.fx2.Position(2)*0.85 app.fx2.Position(3)*0.9 app.fx2.Position(4)*0.465]);
            uit2.ColumnName = {'Target','Distance [m]','Elevation [m]' , 'Heat flux [kw/m²]','Prob of injury type 1 [%]','Prob of injury type 2 [%]','Prob ofdeath [%]' };
           
                
            end
            
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ModelforburningrateDropDown
        function ModelforburningrateDropDownValueChanged(app, event)
            value = app.ModelforburningrateDropDown.Value;
            
            if (value == "2")
                app.Panel_5.Visible ='on' ;
            else
                app.Panel_5.Visible ='off' ;
            end
          %  app.Panel_2.Visible ='on' ;
            
        end

        % Button pushed function: RelevanceButton
        function RelevanceButtonPushed(app, event)
            
              CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            h=msgbox('The models featured in this application are only relevant for \bf unconfined pool fires of hydrocarbons, on the ground and with fixed spill area, with & without wind\rm \newline 2D maps are only available in no-wind conditions','About','help',CreateStruct);


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.f = waitbar(0,'Initializing model','Name','Pool fire modelization');
           
            app.read_input_fields() ; 
            app.run_computations() ;
            app.plot_graphs() ;


        end

        % Close request function: PoolfireUIFigure
        function PoolfireUIFigureCloseRequest(app, event)
            close all force 
            delete(app)
            
        end

        % Button pushed function: AddentreeButton
        function AddentreeButtonPushed(app, event)
        
%           max_targets = 25 ; 
%         custom_targets_array = 0 ; 
%         custom_targets_counter = 0 ; 
%         current_row = 0 ;
%         idx = 0 ;

                if ( (app.custom_targets_counter + 1) <= app.max_targets) 
                    app.idx = app.idx + 1 ;
                    app.custom_targets_counter = app.custom_targets_counter + 1 ;
                    temp = [app.idx   ,  app.TargetdistancemEditField.Value  ,  app.TargetelevationfromgroundmEditField.Value];
                    dat = app.UITable.Data;
                    if(isempty(dat))
                        app.UITable.Data=temp  ;                    
                    else
                        app.UITable.Data = [app.UITable.Data ; temp ] ; 
                    end
                else
                     opts = struct('WindowStyle','modal','Interpreter','tex');
                     errordlg(strcat('You can''t add more than  ',num2str(app.max_targets)),'Error',opts);

                end
          
        
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
%            selectedRow = event.Indices(1);
%            selectedCol = event.Indices(2);
            if (isempty(event.Indices))
                app.current_row = 0 ;
            else
                app.current_row = event.Indices(1);
            end
         %  disp(app.current_row) ;
        

        end

        % Button pushed function: RemoveentreeButton
        function RemoveentreeButtonPushed(app, event)
            if (app.custom_targets_counter >= 1 && app.current_row>0 && app.current_row<=app.custom_targets_counter )
                  
                dat = app.UITable.Data;
                dat(app.current_row , :) = [] ;
                app.UITable.Data = dat ;
                app.custom_targets_counter = app.custom_targets_counter - 1 ;
                
            end
           
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PoolfireUIFigure = uifigure;
            app.PoolfireUIFigure.Name = 'Pool fire ';
            app.PoolfireUIFigure.CloseRequestFcn = createCallbackFcn(app, @PoolfireUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.PoolfireUIFigure);
            app.ScenarioTab = uitab(app.TabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.SpillagePanel = uipanel(app.ScenarioTab);
            app.SpillagePanel.Title = 'Spillage';
            app.TotalVolumem3EditFieldLabel = uilabel(app.SpillagePanel);
            app.TotalVolumem3EditFieldLabel.Text = {'Total Volume'; '(m3)'};
            app.TotalVolumem3EditField = uieditfield(app.SpillagePanel, 'numeric');
            app.TotalVolumem3EditField.Limits = [0.5 Inf];
            app.TotalVolumem3EditField.Value = 10;
            app.PooldiametermEditFieldLabel = uilabel(app.SpillagePanel);
            app.PooldiametermEditFieldLabel.Text = {'Pool diameter'; '(m)'};
            app.PooldiametermEditField = uieditfield(app.SpillagePanel, 'numeric');
            app.PooldiametermEditField.Limits = [0.1 Inf];
            app.PooldiametermEditField.Value = 1;
            app.ThermaleffectsPanel = uipanel(app.ScenarioTab);
            app.ThermaleffectsPanel.Title = 'Thermal effects';
            app.PopulationdensitypersonmEditFieldLabel = uilabel(app.ThermaleffectsPanel);
            app.PopulationdensitypersonmEditFieldLabel.Text = {'Population density'; 'person/m²'; ''};
            app.PopulationdensitypersonmEditField = uieditfield(app.ThermaleffectsPanel, 'numeric');
            app.PopulationdensitypersonmEditField.Limits = [0 Inf];
            app.PopulationdensitypersonmEditField.Tooltip = {'To compute overall injuries/deaths '; 'If not avaiable'; ' leave 0 value'};
            app.PopulationdensitypersonmEditField.Value = 0.05;
            app.BodycoverageimpactofclothesDropDownLabel = uilabel(app.ThermaleffectsPanel);
            app.BodycoverageimpactofclothesDropDownLabel.Text = {'Body coverage'; '(impact of clothes)'};
            app.BodycoverageimpactofclothesDropDown = uidropdown(app.ThermaleffectsPanel);
            app.BodycoverageimpactofclothesDropDown.Items = {'Summer (small coverage)', 'Winter (large coverage)', 'No impact'};
            app.BodycoverageimpactofclothesDropDown.ItemsData = {'0.95', '0.14', '1'};
            app.BodycoverageimpactofclothesDropDown.Value = '0.95';
            app.ThermodynamicpropertiesTab = uitab(app.TabGroup);
            app.ThermodynamicpropertiesTab.Title = 'Thermodynamic. properties';
            app.Panel = uipanel(app.ThermodynamicpropertiesTab);
            app.HeatofvaporizationKjKgLabel = uilabel(app.Panel);
            app.HeatofvaporizationKjKgLabel.Text = {'Heat of vaporization'; '(Kj/Kg)'; ''};
            app.HeatofvaporizationKjKgEditField = uieditfield(app.Panel, 'numeric');
            app.HeatofvaporizationKjKgEditField.Limits = [1 Inf];
            app.HeatofvaporizationKjKgEditField.Value = 1;
            app.HeatofcombustionKjKgEditFieldLabel = uilabel(app.Panel);
            app.HeatofcombustionKjKgEditFieldLabel.Text = {'Heat of combustion'; '(Kj/Kg)'};
            app.HeatofcombustionKjKgEditField = uieditfield(app.Panel, 'numeric');
            app.HeatofcombustionKjKgEditField.Limits = [1 Inf];
            app.HeatofcombustionKjKgEditField.Value = 1;
            app.SpecificheatcapacityKjKgKEditFieldLabel = uilabel(app.Panel);
            app.SpecificheatcapacityKjKgKEditFieldLabel.Text = {'Specific heat capacity'; 'Kj/(Kg.K)'};
            app.SpecificheatcapacityKjKgKEditField = uieditfield(app.Panel, 'numeric');
            app.SpecificheatcapacityKjKgKEditField.Limits = [0.5 Inf];
            app.SpecificheatcapacityKjKgKEditField.Value = 1;
            app.RadiationfractionEditFieldLabel = uilabel(app.Panel);
            app.RadiationfractionEditFieldLabel.Text = 'Radiation fraction';
            app.RadiationfractionEditField = uieditfield(app.Panel, 'numeric');
            app.RadiationfractionEditField.Limits = [0.01 1];
            app.RadiationfractionEditField.Value = 0.2;
            app.DensityKgm3EditFieldLabel = uilabel(app.Panel);
            app.DensityKgm3EditFieldLabel.Text = {'Density'; '(Kg/m3)'};
            app.DensityKgm3EditField = uieditfield(app.Panel, 'numeric');
            app.DensityKgm3EditField.Limits = [1 Inf];
            app.DensityKgm3EditField.Value = 1;
            app.BoilingtemperatureKLabel = uilabel(app.ThermodynamicpropertiesTab);
            app.BoilingtemperatureKLabel.Text = {'Boiling temperature'; '(K)'};
            app.BoilingtemperatureKEditField = uieditfield(app.ThermodynamicpropertiesTab, 'numeric');
            app.BoilingtemperatureKEditField.Limits = [1 Inf];
            app.BoilingtemperatureKEditField.Value = 1;
            app.AmbiantconditionsTab = uitab(app.TabGroup);
            app.AmbiantconditionsTab.Title = 'Ambiant conditions';
            app.Panel_3 = uipanel(app.AmbiantconditionsTab);
            app.AmbianttemperatureKEditFieldLabel = uilabel(app.Panel_3);
            app.AmbianttemperatureKEditFieldLabel.Text = {'Ambiant temperature '; '(K)'};
            app.AmbianttemperatureKEditField = uieditfield(app.Panel_3, 'numeric');
            app.AmbianttemperatureKEditField.Limits = [0 323];
            app.AmbianttemperatureKEditField.Value = 298;
            app.WindvelocitymsEditFieldLabel = uilabel(app.Panel_3);
            app.WindvelocitymsEditFieldLabel.Text = {'Wind velocity'; '(m/s)'};
            app.WindvelocitymsEditField = uieditfield(app.Panel_3, 'numeric');
            app.WindvelocitymsEditField.Limits = [0 Inf];
            app.AirdensityKgm3EditFieldLabel = uilabel(app.Panel_3);
            app.AirdensityKgm3EditFieldLabel.Text = {'Air density'; '(Kg/m3)'};
            app.AirdensityKgm3EditField = uieditfield(app.Panel_3, 'numeric');
            app.AirdensityKgm3EditField.Limits = [0 3];
            app.AirdensityKgm3EditField.Value = 1.184;
            app.AirviscosityuPasEditFieldLabel = uilabel(app.Panel_3);
            app.AirviscosityuPasEditFieldLabel.Text = {'Air viscosity'; '(uPa/s)'};
            app.AirviscosityuPasEditField = uieditfield(app.Panel_3, 'numeric');
            app.AirviscosityuPasEditField.Limits = [0 Inf];
            app.AirviscosityuPasEditField.Value = 18.1;
            app.Humidityratio01EditFieldLabel = uilabel(app.Panel_3);
            app.Humidityratio01EditFieldLabel.Text = {'Humidity ratio'; '[0-1]'};
            app.Humidityratio01EditField = uieditfield(app.Panel_3, 'numeric');
            app.Humidityratio01EditField.Limits = [0 1];
            app.Humidityratio01EditField.Value = 0.7;
            app.SaturationVapourpressurePaEditFieldLabel = uilabel(app.Panel_3);
            app.SaturationVapourpressurePaEditFieldLabel.Text = {'Saturation Vapour'; 'pressure'; '(Pa)'};
            app.SaturationVapourpressurePaEditField = uieditfield(app.Panel_3, 'numeric');
            app.SaturationVapourpressurePaEditField.Limits = [500 100000];
            app.SaturationVapourpressurePaEditField.Value = 3200;
            app.ModelparametersTab = uitab(app.TabGroup);
            app.ModelparametersTab.Title = 'Model parameters';
            app.ComputationsresolutionDropDownLabel = uilabel(app.ModelparametersTab);
            app.ComputationsresolutionDropDownLabel.Text = 'Computations resolution';
            app.ComputationsresolutionDropDown = uidropdown(app.ModelparametersTab);
            app.ComputationsresolutionDropDown.Items = {'Low (faster)', 'Medium ', 'High (slower)'};
            app.ComputationsresolutionDropDown.ItemsData = {'125', '250', '500'};
            app.ComputationsresolutionDropDown.Value = '250';
            app.Applyasafetyfactorofx2CheckBox = uicheckbox(app.ModelparametersTab);
            app.Applyasafetyfactorofx2CheckBox.Tooltip = {'Over-estimate heatflux values by multiplying x2'};
            app.Applyasafetyfactorofx2CheckBox.Text = '  Apply a safety factor of x2';
            app.Panel_5 = uipanel(app.ModelparametersTab);
            app.Panel_5.Visible = 'off';
            app.EquivalentburnrateKgsmEditFieldLabel = uilabel(app.Panel_5);
            app.EquivalentburnrateKgsmEditFieldLabel.Text = {'Equivalent burn rate'; '(Kg/s.m²)'; ''};
            app.EquivalentburnrateKgsmEditField = uieditfield(app.Panel_5, 'numeric');
            app.EquivalentburnrateKgsmEditField.Limits = [0.001 Inf];
            app.EquivalentburnrateKgsmEditField.Tooltip = {'Reference : [Babrauskas 1983'; ''};
            app.EquivalentburnrateKgsmEditField.Value = 0.001;
            app.kbetacoefficientEditFieldLabel = uilabel(app.Panel_5);
            app.kbetacoefficientEditFieldLabel.Text = 'k-beta coefficient';
            app.kbetacoefficientEditField = uieditfield(app.Panel_5, 'numeric');
            app.kbetacoefficientEditField.Limits = [0.5 Inf];
            app.kbetacoefficientEditField.Tooltip = {'Reference : [Babrauskas 1983'; ''};
            app.kbetacoefficientEditField.Value = 0.5;
            app.ModelforburningrateDropDownLabel = uilabel(app.ModelparametersTab);
            app.ModelforburningrateDropDownLabel.Text = 'Model for burning rate';
            app.ModelforburningrateDropDown = uidropdown(app.ModelparametersTab);
            app.ModelforburningrateDropDown.Items = {'Mudan ', 'Zabetakis-Burgess'};
            app.ModelforburningrateDropDown.ItemsData = {'1', '2'};
            app.ModelforburningrateDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelforburningrateDropDownValueChanged, true);
            app.ModelforburningrateDropDown.Value = '1';
            app.ModelforSurfaceemittingpowerDropDownLabel = uilabel(app.ModelparametersTab);
            app.ModelforSurfaceemittingpowerDropDownLabel.Text = {'Model for Surface emitting '; 'power '};
            app.ModelforSurfaceemittingpowerDropDown = uidropdown(app.ModelparametersTab);
            app.ModelforSurfaceemittingpowerDropDown.Items = {'Mudan-Croce ', 'Hagglund & Person'};
            app.ModelforSurfaceemittingpowerDropDown.ItemsData = {'1', '2'};
            app.ModelforSurfaceemittingpowerDropDown.Value = '1';
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.DefinecustomtargetsLabel = uilabel(app.TargetsTab);
            app.DefinecustomtargetsLabel.Text = 'Define custom targets';
            app.AddtargetPanel = uipanel(app.TargetsTab);
            app.AddtargetPanel.Title = 'Add target';
            app.TargetdistancemEditFieldLabel = uilabel(app.AddtargetPanel);
            app.TargetdistancemEditFieldLabel.Text = {'Target distance'; '[m]'};
            app.TargetdistancemEditField = uieditfield(app.AddtargetPanel, 'numeric');
            app.TargetelevationfromgroundmLabel = uilabel(app.AddtargetPanel);
            app.TargetelevationfromgroundmLabel.Text = {'Target elevation'; 'from ground [m]'};
            app.TargetelevationfromgroundmEditField = uieditfield(app.AddtargetPanel, 'numeric');
            app.AddentreeButton = uibutton(app.AddtargetPanel, 'push');
            app.AddentreeButton.ButtonPushedFcn = createCallbackFcn(app, @AddentreeButtonPushed, true);
            app.AddentreeButton.Text = 'Add entree';
            app.Panel_4 = uipanel(app.TargetsTab);
            app.UITable = uitable(app.Panel_4);
            app.UITable.ColumnName = {'Target 1'; 'Distance [m]'; 'Elevation [m]'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RemoveentreeButton = uibutton(app.Panel_4, 'push');
            app.RemoveentreeButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveentreeButtonPushed, true);
            app.RemoveentreeButton.Text = 'Remove entree';
            app.PoolfireLabel = uilabel(app.PoolfireUIFigure);
            app.PoolfireLabel.Text = 'Pool fire';
            app.StartButton = uibutton(app.PoolfireUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start    >';
            app.RelevanceButton = uibutton(app.PoolfireUIFigure, 'push');
            app.RelevanceButton.ButtonPushedFcn = createCallbackFcn(app, @RelevanceButtonPushed, true);
            app.RelevanceButton.Text = 'Relevance';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pool_fire

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PoolfireUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PoolfireUIFigure)
        end
    end
end